classdef computer_vision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ComputerVisionUIFigure        matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CompositesTab                 matlab.ui.container.Tab
        btnTarget                     matlab.ui.control.Button
        btnBrowseLabel                matlab.ui.control.Label
        btnSource                     matlab.ui.control.Button
        PleaseselectyoursourceimagedirectoryLabel  matlab.ui.control.Label
        btnSubmitComposite            matlab.ui.control.Button
        PleaseselectthetypeofcompositionbuilderDropDownLabel  matlab.ui.control.Label
        compositionBuilder            matlab.ui.control.DropDown
        NumberoftilesDropDownLabel    matlab.ui.control.Label
        numTiles                      matlab.ui.control.DropDown
        XLabel                        matlab.ui.control.Label
        numPixelsX                    matlab.ui.control.NumericEditField
        YLabel                        matlab.ui.control.Label
        numPixelsY                    matlab.ui.control.NumericEditField
        ClassifiersTab                matlab.ui.container.Tab
        btnSourceTraining             matlab.ui.control.Button
        btnTestClassifier             matlab.ui.control.Button
        btnSubmitClassifier           matlab.ui.control.Button
        ClassifierTypeDropDownLabel   matlab.ui.control.Label
        classifierType                matlab.ui.control.DropDown
        predictionLabel               matlab.ui.control.Label
        PleaseselectthetypeofcompositionbuilderDropDownLabel_2  matlab.ui.control.Label
        compositionBuilder_2          matlab.ui.control.DropDown
        NumberoftilesDropDownLabel_2  matlab.ui.control.Label
        numTiles_2                    matlab.ui.control.DropDown
        XLabel_2                      matlab.ui.control.Label
        numPixelsX_2                  matlab.ui.control.NumericEditField
        YLabel_2                      matlab.ui.control.Label
        numPixelsY_2                  matlab.ui.control.NumericEditField
        buildingLabel                 matlab.ui.control.Label
        xDropDownLabel                matlab.ui.control.Label
        xDropDown                     matlab.ui.control.DropDown
        yLabel                        matlab.ui.control.Label
        yDropDown                     matlab.ui.control.DropDown
    end

    properties (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%
        % Composite Stuff
        %%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Target Image Properties
        targetImage
        targetImageHeight
        targetImageWidth
        
        % Source Image Properties
        srcImageDirectory
        srcImageSet
        
        % Tiling Properties
        tileWidth
        tileHeight
        
        % AVG RGB Composition Properties
        srcRGBMeanValues
        
        % CCV Composition Properties
        srcCCVValues
        
        % Histogram Properties
        histogramValues
        
        % Final Image Properties
        compositeImage
        compositeImageFun
        
        %%%%%%%%%%%%%%%%%%%%%%%%%
        % Classifier Stuff
        %%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Source Image Properties
        srcTrainingImages
        trainingImgSet;
        
        labels;
        trainingData;
        testData;
        
        % Testing Image Properties
        testImage
        testImageResult
        
    end

    methods (Access = private)

        % Button pushed function: btnTarget
        function btnTargetPushed(app, event)
            app.targetImage = getTargetImage();
        end

        % Button pushed function: btnSubmitComposite
        function btnSubmitCompositePushed(app, event)
            app.targetImage = resizeTargetImage(app.targetImage, app.numPixelsY.Value, app.numPixelsX.Value);
            
            [app.tileWidth, app.tileHeight] = setTileSizes(app.numTiles.Value, app.numPixelsX.Value, app.numPixelsY.Value);
            
            % Determines how the composite image shall be created
            switch(app.compositionBuilder.Value)
                case 'Avg. RGB Values'
                    app.srcRGBMeanValues = buildCompositeLibraryRGBValues(app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                    app.compositeImageFun = @(block_struct) buildRGBComposite(block_struct.data, app.srcRGBMeanValues, app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                case 'Colour Coherence Vector'
                    app.srcCCVValues = buildCompositeLibraryCCVValues(app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                    app.compositeImageFun = @(block_struct) buildCCVComposite(block_struct.data, app.srcCCVValues, app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                case 'Chi-Squared Distance'
                    app.histogramValues = buildCompositeLibraryHistogramValues(app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                    app.compositeImageFun = @(block_struct) buildCDistanceComposite(block_struct.data, app.histogramValues, app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                case 'Euclidean Distance'
                    app.histogramValues = buildCompositeLibraryHistogramValues(app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                    app.compositeImageFun = @(block_struct) buildEDistanceComposite(block_struct.data, app.histogramValues, app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                otherwise
                    fprintf('Error');
            end
            
            app.compositeImage = createCompositeImage(app.targetImage, app.tileWidth, app.tileHeight, app.compositeImageFun);
            
            imshow(app.compositeImage);
        end

        % Button pushed function: btnSource
        function btnSourcePushed(app, event)
            app.srcImageSet = getSourceLibrary();
        end

        % Button pushed function: btnSubmitClassifier
        function btnSubmitClassifierButtonPushed(app, event)
            app.predictionLabel.Text = 'Working...';
           
            switch(app.classifierType.Value)
                
                case 'Bag of Features'
                    app.testImageResult = predictImageLabel(app.testImage, app.srcTrainingImages);
                    
                case 'Knn'
                    
                    % collect all images in sub folders and get labels based on sub folder names
                    [app.trainingImgSet, app.labels] = getImagesFromDirectory(app.srcTrainingImages, 'jpg');
                 
                    % gets various data from image set (e.g. colour, texture, etc.) TODO: get texture details
                    app.trainingData = collectKnnData(app.trainingImgSet, app.xDropDown.Value, app.yDropDown.Value);
                    
                    % collect various data from each test image
                    app.testData = collectTestData(app.testImage, app.xDropDown.Value, app.yDropDown.Value);
                    
                    % get final label result
                    app.testImageResult = Knn(app.testData, app.trainingData, 10, app.labels);
                    
                    % display graph of classifier data
                    gscatter(cell2mat(app.trainingData(:, 1)), cell2mat(app.trainingData(:, 2)), app.labels);


                otherwise
                    fprintf('Error');
                    
            end
            
            
            app.predictionLabel.Text = strcat('Prediction: ', app.testImageResult);
            
            app.buildingLabel.Text = 'Building Composite...';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Part C Composite
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.targetImage = resizeTargetImage(app.testImage, app.numPixelsY_2.Value, app.numPixelsX_2.Value);
            
            [app.tileWidth, app.tileHeight] = setTileSizes(app.numTiles_2.Value, app.numPixelsX_2.Value, app.numPixelsY_2.Value);
            
            % Hack please don't hate
            app.srcImageSet = imageSet(char(fullfile(app.srcTrainingImages, '\', app.testImageResult)));
            
            switch(app.compositionBuilder_2.Value)
                case 'Avg. RGB Values'
                    app.srcRGBMeanValues = buildCompositeLibraryRGBValues(app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                    app.compositeImageFun = @(block_struct) buildRGBComposite(block_struct.data, app.srcRGBMeanValues, app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                case 'Colour Coherence Vector'
                    app.srcCCVValues = buildCompositeLibraryCCVValues(app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                    app.compositeImageFun = @(block_struct) buildCCVComposite(block_struct.data, app.srcCCVValues, app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                case 'Chi-Squared Distance'
                    app.histogramValues = buildCompositeLibraryHistogramValues(app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                    app.compositeImageFun = @(block_struct) buildCDistanceComposite(block_struct.data, app.histogramValues, app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                case 'Euclidean Distance'
                    app.histogramValues = buildCompositeLibraryHistogramValues(app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                    app.compositeImageFun = @(block_struct) buildEDistanceComposite(block_struct.data, app.histogramValues, app.srcImageSet, app.tileWidth, app.tileHeight);
                    
                otherwise
                    fprintf('Error');
            end
            
            app.compositeImage = createCompositeImage(app.targetImage, app.tileWidth, app.tileHeight, app.compositeImageFun);
            
            imshow(app.compositeImage);
            
            app.buildingLabel.Text = 'Completed';
        end

        % Button pushed function: btnSourceTraining
        function btnSourceTrainingButtonPushed(app, event)
            app.srcTrainingImages = uigetdir;
        end

        % Button pushed function: btnTestClassifier
        function btnTestClassifierButtonPushed(app, event)
            app.testImage = getTargetImage();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ComputerVisionUIFigure = uifigure;
            app.ComputerVisionUIFigure.Name = 'Computer Vision';
            app.TabGroup = uitabgroup(app.ComputerVisionUIFigure);
            app.CompositesTab = uitab(app.TabGroup);
            app.CompositesTab.Title = 'Composites';
            app.btnTarget = uibutton(app.CompositesTab, 'push');
            app.btnTarget.ButtonPushedFcn = createCallbackFcn(app, @btnTargetPushed, true);
            app.btnTarget.Text = 'Browse...';
            app.btnBrowseLabel = uilabel(app.CompositesTab);
            app.btnBrowseLabel.Text = 'Please select your target image:';
            app.btnSource = uibutton(app.CompositesTab, 'push');
            app.btnSource.ButtonPushedFcn = createCallbackFcn(app, @btnSourcePushed, true);
            app.btnSource.Text = 'Browse...';
            app.PleaseselectyoursourceimagedirectoryLabel = uilabel(app.CompositesTab);
            app.PleaseselectyoursourceimagedirectoryLabel.Text = 'Please select your source image directory';
            app.btnSubmitComposite = uibutton(app.CompositesTab, 'push');
            app.btnSubmitComposite.ButtonPushedFcn = createCallbackFcn(app, @btnSubmitCompositePushed, true);
            app.btnSubmitComposite.Text = 'Submit';
            app.PleaseselectthetypeofcompositionbuilderDropDownLabel = uilabel(app.CompositesTab);
            app.PleaseselectthetypeofcompositionbuilderDropDownLabel.Text = 'Please select the type of composition builder';
            app.compositionBuilder = uidropdown(app.CompositesTab);
            app.compositionBuilder.Items = {'Avg. RGB Values', 'Colour Coherence Vector', 'Chi-Squared Distance', 'Euclidean Distance'};
            app.compositionBuilder.Value = 'Avg. RGB Values';
            app.NumberoftilesDropDownLabel = uilabel(app.CompositesTab);
            app.NumberoftilesDropDownLabel.Text = 'Number of tiles';
            app.numTiles = uidropdown(app.CompositesTab);
            app.numTiles.Items = {'49', '121', '225', '361', '484', '576', '841', '961', '1156', '1369', '1600', '2025', '2304', '2500', '2809', '3364', '3724', '4489', '4900', '5776', '7225', '10000'};
            app.numTiles.Value = '49';
            app.XLabel = uilabel(app.CompositesTab);
            app.XLabel.Text = 'X';
            app.numPixelsX = uieditfield(app.CompositesTab, 'numeric');
            app.numPixelsX.Limits = [1 Inf];
            app.numPixelsX.ValueDisplayFormat = '%.0f';
            app.numPixelsX.Value = 500;
            app.YLabel = uilabel(app.CompositesTab);
            app.YLabel.Text = 'Y';
            app.numPixelsY = uieditfield(app.CompositesTab, 'numeric');
            app.numPixelsY.Limits = [1 Inf];
            app.numPixelsY.ValueDisplayFormat = '%.0f';
            app.numPixelsY.Value = 500;
            app.ClassifiersTab = uitab(app.TabGroup);
            app.ClassifiersTab.Title = 'Classifiers';
            app.btnSourceTraining = uibutton(app.ClassifiersTab, 'push');
            app.btnSourceTraining.ButtonPushedFcn = createCallbackFcn(app, @btnSourceTrainingButtonPushed, true);
            app.btnSourceTraining.Text = 'Select Training Set';
            app.btnTestClassifier = uibutton(app.ClassifiersTab, 'push');
            app.btnTestClassifier.ButtonPushedFcn = createCallbackFcn(app, @btnTestClassifierButtonPushed, true);
            app.btnTestClassifier.Text = 'Select Image to Test';
            app.btnSubmitClassifier = uibutton(app.ClassifiersTab, 'push');
            app.btnSubmitClassifier.ButtonPushedFcn = createCallbackFcn(app, @btnSubmitClassifierButtonPushed, true);
            app.btnSubmitClassifier.Text = 'Submit';
            app.ClassifierTypeDropDownLabel = uilabel(app.ClassifiersTab);
            app.ClassifierTypeDropDownLabel.Text = 'Classifier Type';
            app.classifierType = uidropdown(app.ClassifiersTab);
            app.classifierType.Items = {'Bag of Features', 'Knn'};
            app.classifierType.Value = 'Bag of Features';
            app.predictionLabel = uilabel(app.ClassifiersTab);
            app.predictionLabel.Text = '';
            app.PleaseselectthetypeofcompositionbuilderDropDownLabel_2 = uilabel(app.ClassifiersTab);
            app.PleaseselectthetypeofcompositionbuilderDropDownLabel_2.Text = 'Please select the type of composition builder';
            app.compositionBuilder_2 = uidropdown(app.ClassifiersTab);
            app.compositionBuilder_2.Items = {'Avg. RGB Values', 'Colour Coherence Vector', 'Chi-Squared Distance', 'Euclidean Distance'};
            app.compositionBuilder_2.Value = 'Avg. RGB Values';
            app.NumberoftilesDropDownLabel_2 = uilabel(app.ClassifiersTab);
            app.NumberoftilesDropDownLabel_2.Text = 'Number of tiles';
            app.numTiles_2 = uidropdown(app.ClassifiersTab);
            app.numTiles_2.Items = {'49', '121', '225', '361', '484', '576', '841', '961', '1156', '1369', '1600', '2025', '2304', '2500', '2809', '3364', '3724', '4489', '4900', '5776', '7225', '10000'};
            app.numTiles_2.Value = '49';
            app.XLabel_2 = uilabel(app.ClassifiersTab);
            app.XLabel_2.Text = 'X';
            app.numPixelsX_2 = uieditfield(app.ClassifiersTab, 'numeric');
            app.numPixelsX_2.Limits = [1 Inf];
            app.numPixelsX_2.ValueDisplayFormat = '%.0f';
            app.numPixelsX_2.Value = 500;
            app.YLabel_2 = uilabel(app.ClassifiersTab);
            app.YLabel_2.Text = 'Y';
            app.numPixelsY_2 = uieditfield(app.ClassifiersTab, 'numeric');
            app.numPixelsY_2.Limits = [1 Inf];
            app.numPixelsY_2.ValueDisplayFormat = '%.0f';
            app.numPixelsY_2.Value = 500;
            app.buildingLabel = uilabel(app.ClassifiersTab);
            app.buildingLabel.Text = '';
            app.xDropDownLabel = uilabel(app.ClassifiersTab);
            app.xDropDownLabel.Text = 'x';
            app.xDropDown = uidropdown(app.ClassifiersTab);
            app.xDropDown.Items = {'Color', 'Edge Intensity', 'Straight Lines', 'Entropy', 'Straight Line Ratio'};
            app.xDropDown.Value = 'Color';
            app.yLabel = uilabel(app.ClassifiersTab);
            app.yLabel.Text = 'y';
            app.yDropDown = uidropdown(app.ClassifiersTab);
            app.yDropDown.Items = {'Color', 'Edge Intensity', 'Straight Lines', 'Entropy', 'Straight Line Ratio'};
            app.yDropDown.Value = 'Straight Lines';
        end
    end

    methods (Access = public)

        % Construct app
        function app = computer_vision

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ComputerVisionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ComputerVisionUIFigure)
        end
    end
end